classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CautionResetthesimulationifyouarentLabel  matlab.ui.control.Label
        AccelerateintheoppositedirectionoftravelLabel  matlab.ui.control.Label
        AccelerateinthedirectionoftravelLabel  matlab.ui.control.Label
        PauseButton              matlab.ui.control.Button
        NeedFuelLabel            matlab.ui.control.Label
        PausedLabel              matlab.ui.control.Label
        LevelListBox             matlab.ui.control.ListBox
        LevelListBoxLabel        matlab.ui.control.Label
        FuelGauge                matlab.ui.control.LinearGauge
        FuelGaugeLabel           matlab.ui.control.Label
        infoLabel                matlab.ui.control.Label
        ShipspeedkmsGauge        matlab.ui.control.Gauge
        ShipspeedkmsLabel        matlab.ui.control.Label
        RetrogradeButton         matlab.ui.control.StateButton
        ProgradeButton           matlab.ui.control.StateButton
        ManualRightButton        matlab.ui.control.StateButton
        ManualDownButton         matlab.ui.control.StateButton
        ManualUpButton           matlab.ui.control.StateButton
        ManualLeftButton         matlab.ui.control.StateButton
        SimulationtimestepMinutesstepSlider  matlab.ui.control.Slider
        SimulationtimestepMinutesstepLabel  matlab.ui.control.Label
        calculationTimeLabel_2   matlab.ui.control.Label
        calculationSpeedLabel_2  matlab.ui.control.Label
        calculationSpeedLabel    matlab.ui.control.Label
        SimulationspeedLabel     matlab.ui.control.Label
        calculationTimeLabel     matlab.ui.control.Label
        CalculationSpeedLabel    matlab.ui.control.Label
        RunsimulationButton      matlab.ui.control.StateButton
        RocketControlsLabel      matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        unlockAllLevels = false;
    end
    
    properties (Access = private)
        buttonList;
    end
       
    
    methods (Access = private)     
        function disableOtherButtons(app, enabledButton)

            for i = 1:size(app.buttonList,2)
                button = app.buttonList(i);
                
                if button == enabledButton
                    continue
                end

                button.Value = false;

                if button == app.RetrogradeButton
                    app.RetrogradeButtonValueChanged(nan)
                elseif button == app.ProgradeButton
                    app.ProgradeButtonValueChanged(nan)
                end
                
            end
        end

        function activateButtons(app, active)
            if not(islogical(active))
                error("Active must be logical.")
            end

            for i = 1:size(app.buttonList,2)
                button = app.buttonList(i);

                button.Enable = active;
            end

            app.NeedFuelLabel.Visible = ~active;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.unlockAllLevels == true
                app.LevelListBox.Items = {'Intro', 'Level 1', 'Level 2','Level 3','Level 4','Level 5','Level 6','Level 7','Challenge','Random'};
            end
            
            app.buttonList = [app.ManualUpButton, ... 
                            app.ManualLeftButton, ...
                            app.ManualDownButton, ...
                            app.ManualRightButton, ...
                            app.ProgradeButton, ...
                            app.RetrogradeButton];

            
            app.RunsimulationButtonValueChanged()
        end

        % Value changed function: RunsimulationButton
        function RunsimulationButtonValueChanged(app, event)
           if app.RunsimulationButton.Value == true
                disp("Starting simulation...")
                app.RunsimulationButton.Text = "Reset ↩️";
                app.CautionResetthesimulationifyouarentLabel.Visible = true;                
                app.RunsimulationButton.BackgroundColor = [0.4,0.4,0.4];
                 
                [bodyList , goal, displayText] = Setup(app.LevelListBox); % setup all the bodies
                app.infoLabel.Text = displayText;
                if bodyList{size(bodyList,2)}.fuel == inf % bodyList{size(bodyList,2)} is the astronaut
                    app.FuelGauge.Visible = false;
                    app.FuelGaugeLabel.Visible = false;
                else
                    app.FuelGauge.Limits = [0,bodyList{size(bodyList,2)}.fuel];
                    app.FuelGauge.Visible = true;
                    app.FuelGaugeLabel.Visible = true;
                end


                for i = size(bodyList,2)
                    body = bodyList{i};
                    if class(body) == "Astronaut"
                        body.setupInputButtons( ...
                            app.ManualUpButton, ... % In order: up left down right, and prograde and retrograde
                            app.ManualLeftButton, ...
                            app.ManualDownButton, ...
                            app.ManualRightButton, ...
                            app.ProgradeButton, ...
                            app.RetrogradeButton) 
                        
                    end

                end
                
                app.disableOtherButtons("none")
                app.activateButtons(true)

                while app.RunsimulationButton.Value == true % main simulation loop
                    simtic = tic;
                    PhysicsObject.dt =  app.SimulationtimestepMinutesstepSlider.Value*60;

                    simulationStep(bodyList,PhysicsObject.dt)
                    
                    % guidata(app.RunsimulationButton);
                    % if app.RunsimulationButton.Value == false
                    %     break
                    % end
                    
                    cla(app.UIAxes)
                    hold(app.UIAxes, "on")                   

                    goal.CheckIfAstronautNearby(bodyList{size(bodyList,2)}) % bodyList{size(bodyList,2)} is the astronaut

                    % === Axes plotting ===                   


                    goal.DrawSelf(app.UIAxes)
                    
                    for j = 1:size(bodyList,2)
                        bodyList{j}.DrawSelf(app.UIAxes,true)
                    end                   
                    
                    if bodyList{size(bodyList,2)}.dt == 0    
                        positionVector = bodyList{size(bodyList,2)}.position;
                        velocityVector = bodyList{size(bodyList,2)}.position + bodyList{size(bodyList,2)}.velocity * 1e5;
                        plot(app.UIAxes,[positionVector(1,:),velocityVector(1,:)],[positionVector(2,:),velocityVector(2,:)], ...
                            Color="black")
                    end
                                      
                    pause(0.01);
                    drawnow;% limitrate was here but it prevents the simulation from stopping quickly

                    simtime = toc(simtic);                    

                    % === UI Control ===

                    if bodyList{size(bodyList,2)}.crashed == true
                        app.RunsimulationButton.Value = false;
                        app.RunsimulationButtonValueChanged()
                        app.RunsimulationButton.Text = "Crashed! ☠️ Reset?";
                        app.CautionResetthesimulationifyouarentLabel.Visible = false;
                        app.RunsimulationButton.BackgroundColor = [0.8,0.3,0.3];
                    end
                    
                   
                    if isfinite(bodyList{size(bodyList,2)}.fuel)
                        app.FuelGauge.Value = bodyList{size(bodyList,2)}.fuel;
                        if bodyList{size(bodyList,2)}.fuel < 0
                            app.activateButtons(false)
                        end
                    end

                    if bodyList{size(bodyList,2)}.dt == 0
                        app.PausedLabel.Visible = true;
                        app.PauseButton.Text = "▶️";
                    else
                        app.PausedLabel.Visible = false;
                        app.PauseButton.Text = "⏸️";
                    end

                    simulationSpeed = 1/simtime * PhysicsObject.dt / (60 * 60);
                    app.calculationTimeLabel.Text = string(round(1/simtime));
                    app.calculationSpeedLabel.Text = string(round(simulationSpeed));
                    app.ShipspeedkmsGauge.Value = norm(bodyList{size(bodyList,2)}.velocity)/1000;
                end             

                
           else
               disp("Simulation stopped.")
               app.RunsimulationButton.Text = "Run simulation";
               app.CautionResetthesimulationifyouarentLabel.Visible = false;
               app.RunsimulationButton.BackgroundColor = [0.9,0.9,0.9];
            end
            
        end

        % Value changed function: RetrogradeButton
        function RetrogradeButtonValueChanged(app, event)
            value = app.RetrogradeButton.Value;            

            if value == true
                app.RetrogradeButton.BackgroundColor = app.RetrogradeButton.BackgroundColor .^ 2;
                app.disableOtherButtons(app.RetrogradeButton)
            else
                app.RetrogradeButton.BackgroundColor = [0.85,0.25,0.25];
            end
        end

        % Value changed function: ProgradeButton
        function ProgradeButtonValueChanged(app, event)
            value = app.ProgradeButton.Value;
            
            if value == true
                app.ProgradeButton.BackgroundColor = app.ProgradeButton.BackgroundColor .^ 2;
                app.disableOtherButtons(app.ProgradeButton)
            else
                app.ProgradeButton.BackgroundColor = [0.39,0.83,0.07];
            end
        end

        % Value changed function: ManualDownButton, ManualLeftButton, 
        % ...and 2 other components
        function ManualButtonValueChanged(app, event)
            value = event.Source.Value;
            
            if value == true
                app.disableOtherButtons(event.Source);
            end

        end

        % Value changed function: LevelListBox
        function LevelListBoxValueChanged(app, event)
            app.SimulationtimestepMinutesstepSlider.Value = 0;
            app.RunsimulationButton.Value = false;
            app.RunsimulationButtonValueChanged()
            app.RunsimulationButton.Text = "Load Level 💾";
            app.CautionResetthesimulationifyouarentLabel.Visible = false;
            app.RunsimulationButton.BackgroundColor = [0.8,0.8,0.3];
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.SimulationtimestepMinutesstepSlider.Value ~= 0
                app.SimulationtimestepMinutesstepSlider.Value = 0;
            else
                app.SimulationtimestepMinutesstepSlider.Value = 20;
                if app.RunsimulationButton.Value == false
                    app.RunsimulationButton.Value = true;
                    app.RunsimulationButtonValueChanged()
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulation (not to scale)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1000000000];
            app.UIAxes.YLim = [0 1000000000];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.RocketControlsLabel = uilabel(app.UIFigure);
            app.RocketControlsLabel.Text = 'Rocket Controls';
            app.RunsimulationButton = uibutton(app.UIFigure, 'state');
            app.RunsimulationButton.ValueChangedFcn = createCallbackFcn(app, @RunsimulationButtonValueChanged, true);
            app.RunsimulationButton.Text = {'Run '; 'simulation'};
            app.RunsimulationButton.Value = true;
            app.CalculationSpeedLabel = uilabel(app.UIFigure);
            app.CalculationSpeedLabel.Text = 'Calculation Speed:';
            app.calculationTimeLabel = uilabel(app.UIFigure);
            app.calculationTimeLabel.Text = 'n/a';
            app.SimulationspeedLabel = uilabel(app.UIFigure);
            app.SimulationspeedLabel.Text = 'Simulation speed:';
            app.calculationSpeedLabel = uilabel(app.UIFigure);
            app.calculationSpeedLabel.Text = 'n/a';
            app.calculationSpeedLabel_2 = uilabel(app.UIFigure);
            app.calculationSpeedLabel_2.Text = 'hours/s';
            app.calculationTimeLabel_2 = uilabel(app.UIFigure);
            app.calculationTimeLabel_2.Text = 'steps/s';
            app.SimulationtimestepMinutesstepLabel = uilabel(app.UIFigure);
            app.SimulationtimestepMinutesstepLabel.Text = '⏱️ Simulation time step (Minutes / step)';
            app.SimulationtimestepMinutesstepSlider = uislider(app.UIFigure);
            app.SimulationtimestepMinutesstepSlider.Limits = [0 120];
            app.SimulationtimestepMinutesstepSlider.MajorTicks = [0 20 40 60 80 100 120];
            app.SimulationtimestepMinutesstepSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120'};
            app.SimulationtimestepMinutesstepSlider.MinorTicks = [10 30 50 70 90 110];
            app.SimulationtimestepMinutesstepSlider.Value = 20;
            app.ManualLeftButton = uibutton(app.UIFigure, 'state');
            app.ManualLeftButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualLeftButton.Text = '⬅️';
            app.ManualUpButton = uibutton(app.UIFigure, 'state');
            app.ManualUpButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualUpButton.Text = '⬆️';
            app.ManualDownButton = uibutton(app.UIFigure, 'state');
            app.ManualDownButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualDownButton.Text = '⬇️';
            app.ManualRightButton = uibutton(app.UIFigure, 'state');
            app.ManualRightButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualRightButton.Text = '➡️';
            app.ProgradeButton = uibutton(app.UIFigure, 'state');
            app.ProgradeButton.ValueChangedFcn = createCallbackFcn(app, @ProgradeButtonValueChanged, true);
            app.ProgradeButton.Tooltip = {'Accelerate in the direction of travel'};
            app.ProgradeButton.Text = 'Prograde 💨';
            app.RetrogradeButton = uibutton(app.UIFigure, 'state');
            app.RetrogradeButton.ValueChangedFcn = createCallbackFcn(app, @RetrogradeButtonValueChanged, true);
            app.RetrogradeButton.Tooltip = {'Accelerate away from the direction of travel'};
            app.RetrogradeButton.Text = 'Retrograde 🐢';
            app.ShipspeedkmsLabel = uilabel(app.UIFigure);
            app.ShipspeedkmsLabel.Text = 'Ship speed (km/s)';
            app.ShipspeedkmsGauge = uigauge(app.UIFigure, 'circular');
            app.ShipspeedkmsGauge.Limits = [0 3];
            app.infoLabel = uilabel(app.UIFigure);
            app.infoLabel.Text = 'Welcome to Spacehopper';
            app.FuelGaugeLabel = uilabel(app.UIFigure);
            app.FuelGaugeLabel.Visible = 'off';
            app.FuelGaugeLabel.Text = 'Fuel';
            app.FuelGauge = uigauge(app.UIFigure, 'linear');
            app.FuelGauge.Visible = 'off';
            app.FuelGauge.Value = 100;
            app.LevelListBoxLabel = uilabel(app.UIFigure);
            app.LevelListBoxLabel.Text = 'Level';
            app.LevelListBox = uilistbox(app.UIFigure);
            app.LevelListBox.Items = {'Intro', 'Level 1'};
            app.LevelListBox.ValueChangedFcn = createCallbackFcn(app, @LevelListBoxValueChanged, true);
            app.LevelListBox.Value = 'Intro';
            app.PausedLabel = uilabel(app.UIFigure);
            app.PausedLabel.Visible = 'off';
            app.PausedLabel.Text = '⏸️';
            app.NeedFuelLabel = uilabel(app.UIFigure);
            app.NeedFuelLabel.Visible = 'off';
            app.NeedFuelLabel.Text = '⛽';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = '⏸️';
            app.AccelerateinthedirectionoftravelLabel = uilabel(app.UIFigure);
            app.AccelerateinthedirectionoftravelLabel.Text = 'Accelerate in the direction of travel';
            app.AccelerateintheoppositedirectionoftravelLabel = uilabel(app.UIFigure);
            app.AccelerateintheoppositedirectionoftravelLabel.Text = 'Accelerate in the opposite direction of travel';
            app.CautionResetthesimulationifyouarentLabel = uilabel(app.UIFigure);
            app.CautionResetthesimulationifyouarentLabel.Text = 'Caution: Stop simulation if you go afk. It will chew through battery power on a laptop.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end